classdef project03_randomWalk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StartStopButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        RandomWalkLabel                 matlab.ui.control.Label
        ProgramLogTextAreaLabel         matlab.ui.control.Label
        ProgramLogTextArea              matlab.ui.control.TextArea
        CoordinatesPanel                matlab.ui.container.Panel
        currentXCoordinateLabel         matlab.ui.control.Label
        XCoordinateValueLabel           matlab.ui.control.Label
        currentYCoordinateLabel         matlab.ui.control.Label
        YCoordinateValueLabel           matlab.ui.control.Label
        currentZCoordinateLabel         matlab.ui.control.Label
        ZCoordinateValueLabel           matlab.ui.control.Label
        MarkerOptionsPanel              matlab.ui.container.Panel
        MarkerStyleDropDownLabel        matlab.ui.control.Label
        MarkerStyleDropDown             matlab.ui.control.DropDown
        MarkerSizeSpinnerLabel          matlab.ui.control.Label
        MarkerSizeSpinner               matlab.ui.control.Spinner
        MarkerFaceButton                matlab.ui.control.Button
        MarkerEdgeButton                matlab.ui.control.Button
        AxisLimitsSliderLabel           matlab.ui.control.Label
        AxisLimitsSlider                matlab.ui.control.Slider
        AxisLimitOffsetSliderLabel      matlab.ui.control.Label
        AxisLimitOffsetSlider           matlab.ui.control.Slider
        StepSizeEditFieldLabel          matlab.ui.control.Label
        StepSizeEditField               matlab.ui.control.NumericEditField
        TraillineStyleDropDownLabel     matlab.ui.control.Label
        TraillineStyleDropDown          matlab.ui.control.DropDown
        TraillineColorDropDownLabel     matlab.ui.control.Label
        TraillineColorDropDown          matlab.ui.control.DropDown
        TraillineWidthEditFieldLabel    matlab.ui.control.Label
        TraillineWidthEditField         matlab.ui.control.NumericEditField
        NumberofdirectionsSpinnerLabel  matlab.ui.control.Label
        NumberofdirectionsSpinner       matlab.ui.control.Spinner
        DISCRETEMODEButton              matlab.ui.control.StateButton
        TORUSMODEButton                 matlab.ui.control.Button
        DMODEButton                     matlab.ui.control.Button
        RESETButton                     matlab.ui.control.Button
        studentNumbersLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        running  % keep if the app is running
        stepChanged
        xLimOffset; yLimOffset; zLimOffset; % The amount to increase/decrease the axes limits
        position  % the position of the point
        walkPlot  % the handle to the animation on the UIAxes
        delta  % The amount to increase/decrease the axes limits
        styleChanged
        sizeChanged
        faceColorChanged; faceColor
        edgeColorChanged; edgeColor
        axesLimitsChanged; axesLimitsOffsetChanged
        h % the trail line object
        lineStyle % style of trail
        lineColor % color of trail
        lineWidth % width of trail
        lineStyleChanged;
        lineColorChanged;
        lineWidthChanged;
        button; % switch from discrete mode (1) to continuous angle mode (0)
        n % number of discrete directions to move
        discreteMode
        torusMode
        twoDMode
        threeDMode
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.running = false;
            app.stepChanged = false;
            app.delta = 1;
            app.discreteMode = false;
            app.n = 3;
            
            app.lineStyle = '-';
            app.lineColor = 'r';
            app.lineWidth = 2;
            
            app.faceColor = 'r';
            app.edgeColor = 'k';
            
            app.UIAxes.XLim = [-app.AxisLimitsSlider.Value, app.AxisLimitsSlider.Value];
            app.UIAxes.YLim = [-app.AxisLimitsSlider.Value, app.AxisLimitsSlider.Value];
            app.UIAxes.ZLim = [-app.AxisLimitsSlider.Value, app.AxisLimitsSlider.Value];
            
            app.xLimOffset = app.AxisLimitOffsetSlider.Value;
            app.yLimOffset = app.AxisLimitOffsetSlider.Value;
            app.zLimOffset = app.AxisLimitOffsetSlider.Value;
            
            app.twoDMode = true;
            app.threeDMode = false;
            app.torusMode = false;
            
            % Plot the initial point (2d is the default mode)
            app.position = [0 0];
            app.walkPlot = plot(app.UIAxes, app.position(1), app.position(2), app.MarkerStyleDropDown.Value, ...
                'MarkerSize', app.MarkerSizeSpinner.Value, ...
                'MarkerFaceColor', app.faceColor, ...
                'MarkerEdgeColor', app.edgeColor);
            
            % Create an animated line
            app.h = animatedline(app.UIAxes,'LineStyle', app.lineStyle, 'Color',app.lineColor, ...
                'LineWidth',app.lineWidth);
        end

        % Button pushed function: StartStopButton
        function StartStopButtonPushed(app, event)
            if app.running % if the app is running: stop it
                app.ProgramLogTextArea.Value{end+1} = 'Stopped';
                app.running = false;
            else
                app.running = true;
                app.StartStopButton.Text = "Stop";
                app.ProgramLogTextArea.Value{end+1} = 'Started';
                
                try
                    while app.running  % the program running loop
                        
                        if app.twoDMode
                            % normal mode vs continuous mode
                            if ~app.discreteMode % normal mode (continuous)
                                % Updating the animation
                                theta = rand*2*pi; % Generate a random angle
                            else % discrete mode
                                theta = unidrnd(app.n)*2*pi/app.n;
                            end
                            
                            if app.torusMode
                                if app.position(1) < app.UIAxes.XLim(1)
                                    app.position(1) = app.UIAxes.XLim(2);
                                elseif app.position(1) > app.UIAxes.XLim(2)
                                    app.position(1) = app.UIAxes.XLim(1);
                                end
                                if app.position(2) < app.UIAxes.YLim(1)
                                    app.position(2) = app.UIAxes.XLim(2);
                                elseif app.position(2) > app.UIAxes.YLim(2)
                                    app.position(2) = app.UIAxes.YLim(1);
                                end
                                
                            else % if we are not in torus mode, then we update the axes limits
                                % Check axes limits. We can update XLim and YLim in place
                                if app.position(1) < app.UIAxes.XLim
                                    app.UIAxes.XLim(1) = app.UIAxes.XLim(1) - app.xLimOffset;
                                elseif app.position(1) > app.UIAxes.XLim(2)
                                    app.UIAxes.XLim(2) = app.UIAxes.XLim(2) + app.xLimOffset;
                                end
                                if app.position(2) < app.UIAxes.YLim(1)
                                    app.UIAxes.YLim(1) = app.UIAxes.YLim(1) - app.yLimOffset;
                                elseif app.position(2) > app.UIAxes.YLim(2)
                                    app.UIAxes.YLim(2) = app.UIAxes.YLim(2) + app.yLimOffset;
                                end
                            end
                            
                            app.position = app.position + app.StepSizeEditField.Value * [cos(theta) sin(theta)]; % Update coordinates
                            % Update plot XData and YData
                            app.walkPlot.XData = app.position(1);
                            app.walkPlot.YData = app.position(2);
                            addpoints(app.h, app.position(1), app.position(2)) % Draw the trail line
                            
                            % showing the x, y (and z) values
                            app.XCoordinateValueLabel.Text = num2str(app.position(1));
                            app.YCoordinateValueLabel.Text = num2str(app.position(2));
                            
                        else % 3d mode
                            if ~app.discreteMode
                                u = rand;
                                v = rand;
                                theta = acos(1-2*v);    % Generate a random angle
                                phi = 2*pi*u;           % Generate a random angle
                                app.position = app.position + app.StepSizeEditField.Value * [sin(theta)*cos(phi) sin(theta)*sin(phi) cos(theta)]; % Update coordinates
                            else
                                app.position = app.StepSizeEditField.Value * [randi([1,6]) randi([1,6]) randi([1,6])]; % Update coordinates
                            end
                            
                            if app.torusMode
                                if app.position(1) < app.UIAxes.XLim(1)
                                    app.position(1) = app.UIAxes.XLim(2);
                                elseif app.position(1) > app.UIAxes.XLim(2)
                                    app.position(1) = app.UIAxes.XLim(1);
                                end
                                if app.position(2) < app.UIAxes.YLim(1)
                                    app.position(2) = app.UIAxes.XLim(2);
                                elseif app.position(2) > app.UIAxes.YLim(2)
                                    app.position(2) = app.UIAxes.YLim(1);
                                end
                                if app.position(3) < app.UIAxes.ZLim(1)
                                    app.position(3) = app.UIAxes.ZLim(2);
                                elseif app.position(3) > app.UIAxes.ZLim(2)
                                    app.position(3) = app.UIAxes.ZLim(1);
                                end
                                
                            else % if we are not in torus mode, then we update the axes limits
                                % Check axes limits. We can update XLim and YLim in place
                                if app.position(1) < app.UIAxes.XLim(1)
                                    app.UIAxes.XLim(1) = app.UIAxes.XLim(1) - app.xLimOffset;
                                elseif app.position(1) > app.UIAxes.XLim(2)
                                    app.UIAxes.XLim(2) = app.UIAxes.XLim(2) + app.xLimOffset;
                                end
                                
                                if app.position(2) < app.UIAxes.YLim(1)
                                    app.UIAxes.YLim(1) = app.UIAxes.YLim(1) - app.yLimOffset;
                                elseif app.position(2) > app.UIAxes.YLim(2)
                                    app.UIAxes.YLim(2) = app.UIAxes.YLim(2) + app.yLimOffset;
                                end
                                
                                if app.position(3) < app.UIAxes.ZLim(1)
                                    app.UIAxes.ZLim(1) = app.UIAxes.ZLim(1) - app.zLimOffset;
                                elseif app.position(3) > app.UIAxes.ZLim(2)
                                    app.UIAxes.ZLim(2) = app.UIAxes.ZLim(2) + app.zLimOffset;
                                end
                            end
                            
                            app.walkPlot.XData = app.position(1);
                            app.walkPlot.YData = app.position(2);
                            app.walkPlot.ZData = app.position(3);
                            addpoints(app.h, app.position(1), app.position(2), app.position(3)) % Draw the trail line
                            
                            % showing the x, y and z values
                            app.XCoordinateValueLabel.Text = num2str(app.position(1));
                            app.YCoordinateValueLabel.Text = num2str(app.position(2));
                            app.ZCoordinateValueLabel.Text = num2str(app.position(3));
                            
                        end
                        
                        % update plot marker style
                        if app.styleChanged
                            app.walkPlot.Marker = app.MarkerStyleDropDown.Value;
                            app.styleChanged = false;
                            app.ProgramLogTextArea.Value{end+1} = 'Marker Style Changed';
                        end
                        % update plot marker size
                        if app.sizeChanged
                            app.walkPlot.MarkerSize = app.MarkerSizeSpinner.Value;
                            app.sizeChanged = false;
                            app.ProgramLogTextArea.Value{end+1} = 'Marker Size Changed';
                        end
                        % update plot marker face color
                        if app.faceColorChanged
                            app.walkPlot.MarkerFaceColor = app.faceColor;
                            app.faceColorChanged = false;
                            app.ProgramLogTextArea.Value{end+1} = 'Marker Face Color Changed';
                        end
                        % update plot marker edge color
                        if app.edgeColorChanged
                            app.walkPlot.MarkerEdgeColor = app.edgeColor;
                            app.edgeColorChanged = false;
                            app.ProgramLogTextArea.Value{end+1} = 'Marker Edge Color Changed';
                        end
                        % update line style
                        if app.lineStyleChanged
                            app.h.LineStyle = app.lineStyle;
                            app.lineStyleChanged = false;
                            app.ProgramLogTextArea.Value{end+1} = 'Line Style Changed';
                        end
                        % update line color
                        if app.lineColorChanged
                            app.h.Color = app.lineColor;
                            app.lineColorChanged = false;
                            app.ProgramLogTextArea.Value{end+1} = 'Line Color Changed';
                        end
                        if app.lineWidthChanged
                            app.h.LineWidth = app.lineWidth;
                            app.lineWidthChanged = false;
                            app.ProgramLogTextArea.Value{end+1} = 'Line Width Changed';
                        end
                        
                        if app.axesLimitsChanged
                            app.UIAxes.XLim = [-app.AxisLimitsSlider.Value, app.AxisLimitsSlider.Value];
                            app.UIAxes.YLim = [-app.AxisLimitsSlider.Value, app.AxisLimitsSlider.Value];
                            app.UIAxes.ZLim = [-app.AxisLimitsSlider.Value, app.AxisLimitsSlider.Value];
                            app.axesLimitsChanged = false;
                            app.ProgramLogTextArea.Value{end+1} = 'Axes Limits Changed';
                        end
                        
                        if app.axesLimitsOffsetChanged
                            app.xLimOffset = app.AxisLimitOffsetSlider.Value;
                            app.yLimOffset = app.AxisLimitOffsetSlider.Value;
                            app.zLimOffset = app.AxisLimitOffsetSlider.Value;
                            app.axesLimitsOffsetChanged = false;
                            app.ProgramLogTextArea.Value{end+1} = 'Axes Limits Offset Changed';
                        end
                        
                        if strcmpi(app.lineStyle, 'none') && strcmpi(app.MarkerStyleDropDown.Value, 'none')
                            errorDialog = errordlg(['Line Style and Marker Style cannot be none at the same time, ' ...
                                'go back to the app and change 1 value to other thing, then click ok']);
                            waitfor(errorDialog);
                        end
                        
                        drawnow % Draw the new plot
                        
                    end
                    
                    app.StartStopButton.Text = "Start";
                    
                catch ME
                    disp(ME);
                end
                
            end
            
        end

        % Value changed function: MarkerStyleDropDown
        function MarkerStyleDropDownValueChanged(app, event)
            app.styleChanged = true;
        end

        % Value changed function: MarkerSizeSpinner
        function MarkerSizeSpinnerValueChanged(app, event)
            app.sizeChanged = true;
        end

        % Button pushed function: MarkerFaceButton
        function MarkerFaceButtonPushed(app, event)
            app.faceColor =  uisetcolor;
            app.faceColorChanged = true;
        end

        % Button pushed function: MarkerEdgeButton
        function MarkerEdgeButtonPushed(app, event)
            app.edgeColor =  uisetcolor;
            app.edgeColorChanged = true;
        end

        % Value changed function: AxisLimitsSlider
        function AxisLimitsSliderValueChanged(app, event)
            app.axesLimitsChanged = true;
        end

        % Value changed function: AxisLimitOffsetSlider
        function AxisLimitOffsetSliderValueChanged(app, event)
            app.axesLimitsOffsetChanged = true;
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            latestvalue = event.Value;
            app.StepSizeEditField.Value = latestvalue;
            app.stepChanged = true;
        end

        % Value changed function: TraillineStyleDropDown
        function TraillineStyleDropDownValueChanged(app, event)
            app.lineStyleChanged = true;
            app.lineStyle = app.TraillineStyleDropDown.Value;
        end

        % Value changed function: TraillineColorDropDown
        function TraillineColorDropDownValueChanged(app, event)
            app.lineColorChanged = true;
            app.lineColor = app.TraillineColorDropDown.Value;
        end

        % Value changed function: TraillineWidthEditField
        function TraillineWidthEditFieldValueChanged(app, event)
            app.lineWidthChanged = true;
            app.lineWidth = app.TraillineWidthEditField.Value;
        end

        % Value changed function: NumberofdirectionsSpinner
        function NumberofdirectionsSpinnerValueChanged(app, event)
            app.n = app.NumberofdirectionsSpinner.Value;
        end

        % Value changed function: DISCRETEMODEButton
        function DISCRETEMODEButtonValueChanged(app, event)
            if app.discreteMode
                app.discreteMode = false;
                app.ProgramLogTextArea.Value{end+1} = 'EXIT DISCRETE MODE';
                app.DISCRETEMODEButton.Text = 'DISCRETE MODE';
            else
                app.discreteMode = true;
                app.ProgramLogTextArea.Value{end+1} = 'DISCRETE MODE';
                app.DISCRETEMODEButton.Text = 'EXIT DISCRETE MODE';
            end
        end

        % Button pushed function: TORUSMODEButton
        function TORUSMODEButtonPushed(app, event)
            if app.torusMode % if we are running the torus mode and the button is clicked
                app.torusMode = false;
                app.TORUSMODEButton.Text = 'TORUS MODE';
                app.ProgramLogTextArea.Value{end+1} = 'EXIT TORUS MODE';
            else % if the torus mode is not running and we click this button
                app.torusMode = true;
                app.TORUSMODEButton.Text = 'EXIT TORUS MODE';
                app.ProgramLogTextArea.Value{end+1} = 'TORUS MODE';
            end
        end

        % Button pushed function: DMODEButton
        function DMODEButtonPushed(app, event)
            if app.twoDMode  % if the app is running 2d mode and the button is clicked, we change to 3d mode
                hold(app.UIAxes, 'on');
                app.UIAxes.View = [45 45];
                app.position = [0 0 0];
                app.twoDMode = false;
                app.threeDMode = true;
                app.DMODEButton.Text = '2D MODE';
                app.ProgramLogTextArea.Value{end+1} = 'Changed to 3D mode';
                app.walkPlot = plot3(app.UIAxes, app.position(1), app.position(2), app.position(3),...
                    'Marker', app.MarkerStyleDropDown.Value, ...
                    'MarkerSize', app.MarkerSizeSpinner.Value, ...
                    'MarkerFaceColor', app.faceColor, ...
                    'MarkerEdgeColor', app.edgeColor);
                app.h = animatedline(app.UIAxes,'LineStyle', app.lineStyle, 'Color',app.lineColor, ...
                    'LineWidth',app.lineWidth);
                drawnow % Draw the new plot
            else
                hold(app.UIAxes, 'on');
                app.UIAxes.View = [0 90];
                app.position = [0 0];
                app.twoDMode = true;
                app.threeDMode = false;
                app.DMODEButton.Text = '3D MODE';
                app.ProgramLogTextArea.Value{end+1} = 'Changed to 2D mode';
                app.walkPlot = plot(app.UIAxes, app.position(1), app.position(2), ...
                    'Marker', app.MarkerStyleDropDown.Value, ...
                    'MarkerSize', app.MarkerSizeSpinner.Value, ...
                    'MarkerFaceColor', app.faceColor, ...
                    'MarkerEdgeColor', app.edgeColor);
                app.h = animatedline(app.UIAxes,'LineStyle', app.lineStyle, 'Color',app.lineColor, ...
                    'LineWidth',app.lineWidth);
                drawnow % Draw the new plot
            end
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.running = false;
            app.discreteMode = false;
            app.torusMode = false;
            app.TORUSMODEButton.Text = 'TORUS MODE';
            app.DISCRETEMODEButton.Text = 'DISCRETE MODE';
            app.ProgramLogTextArea.Value = ['Welcome, dear customer. If you like this program, ' ...
                'make sure to leave a preview on Play Store. ' ...
                'We also have a subcription plan for only 5€/month.'];
            app.UIAxes.cla;
            app.UIAxes.XLim = [-1, 1];
            app.UIAxes.YLim = [-1, 1];
            app.UIAxes.ZLim = [-1, 1];
            app.xLimOffset = 1;
            app.yLimOffset = 1;
            app.zLimOffset = 1;
            app.AxisLimitsSlider.Value = 1;
            app.AxisLimitOffsetSlider.Value = 1;
            app.StepSizeEditField.Value = 0.5;
            if app.twoDMode
                app.position = [0 0];
                app.walkPlot = plot(app.UIAxes, app.position(1), app.position(2), app.MarkerStyleDropDown.Value, ...
                    'MarkerSize', app.MarkerSizeSpinner.Value, ...
                    'MarkerFaceColor', app.faceColor, ...
                    'MarkerEdgeColor', app.edgeColor);
                app.h = animatedline(app.UIAxes,'LineStyle', app.lineStyle, 'Color',app.lineColor, ...
                    'LineWidth',app.lineWidth);
            else
                app.position = [0 0 0];
                app.walkPlot = plot3(app.UIAxes, app.position(1), app.position(2), app.position(3),...
                    'Marker', app.MarkerStyleDropDown.Value, ...
                    'MarkerSize', app.MarkerSizeSpinner.Value, ...
                    'MarkerFaceColor', app.faceColor, ...
                    'MarkerEdgeColor', app.edgeColor);
                app.h = animatedline(app.UIAxes,'LineStyle', app.lineStyle, 'Color',app.lineColor, ...
                    'LineWidth',app.lineWidth);
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            f = msgbox('Good bye, Moi Moi');
            pause(0.7);
            delete(f);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.StartStopButton = uibutton(app.UIFigure, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopButtonPushed, true);
            app.StartStopButton.Text = 'Start';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-1 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.ZLim = [-1 1];
            app.RandomWalkLabel = uilabel(app.UIFigure);
            app.RandomWalkLabel.Text = 'Random Walk';
            app.ProgramLogTextAreaLabel = uilabel(app.UIFigure);
            app.ProgramLogTextAreaLabel.Text = 'Program Log';
            app.ProgramLogTextArea = uitextarea(app.UIFigure);
            app.ProgramLogTextArea.Value = {'Welcome, dear customer. If you like this program, make sure to leave a preview on Play Store. We also have a subcription plan for only 5€/month.'};
            app.CoordinatesPanel = uipanel(app.UIFigure);
            app.CoordinatesPanel.Title = 'Coordinates';
            app.currentXCoordinateLabel = uilabel(app.CoordinatesPanel);
            app.currentXCoordinateLabel.Text = 'current x coordinate';
            app.XCoordinateValueLabel = uilabel(app.CoordinatesPanel);
            app.XCoordinateValueLabel.Text = '';
            app.currentYCoordinateLabel = uilabel(app.CoordinatesPanel);
            app.currentYCoordinateLabel.Text = 'current y coordinate';
            app.YCoordinateValueLabel = uilabel(app.CoordinatesPanel);
            app.YCoordinateValueLabel.Text = '';
            app.currentZCoordinateLabel = uilabel(app.CoordinatesPanel);
            app.currentZCoordinateLabel.Text = 'current z coordinate';
            app.ZCoordinateValueLabel = uilabel(app.CoordinatesPanel);
            app.ZCoordinateValueLabel.Text = '';
            app.MarkerOptionsPanel = uipanel(app.UIFigure);
            app.MarkerOptionsPanel.Title = 'Marker Options';
            app.MarkerStyleDropDownLabel = uilabel(app.MarkerOptionsPanel);
            app.MarkerStyleDropDownLabel.Text = 'Marker Style';
            app.MarkerStyleDropDown = uidropdown(app.MarkerOptionsPanel);
            app.MarkerStyleDropDown.Items = {'Circle', 'Plus', 'Asterisk', 'Point', 'Cross', 'Square', 'Diamond', 'Upward-point triangle', 'Downward-pointing triangle', 'Right-pointing ', 'Left-pointing triangle', 'Pentagram', 'Hexagram', 'None'};
            app.MarkerStyleDropDown.ItemsData = {'o', '+', '*', '.', 'x', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'};
            app.MarkerStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerStyleDropDownValueChanged, true);
            app.MarkerStyleDropDown.Value = 'o';
            app.MarkerSizeSpinnerLabel = uilabel(app.MarkerOptionsPanel);
            app.MarkerSizeSpinnerLabel.Text = 'Marker Size';
            app.MarkerSizeSpinner = uispinner(app.MarkerOptionsPanel);
            app.MarkerSizeSpinner.Limits = [1 50];
            app.MarkerSizeSpinner.ValueDisplayFormat = '%.0f';
            app.MarkerSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSpinnerValueChanged, true);
            app.MarkerSizeSpinner.Value = 5;
            app.MarkerFaceButton = uibutton(app.MarkerOptionsPanel, 'push');
            app.MarkerFaceButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerFaceButtonPushed, true);
            app.MarkerFaceButton.Text = 'Marker Face';
            app.MarkerEdgeButton = uibutton(app.MarkerOptionsPanel, 'push');
            app.MarkerEdgeButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerEdgeButtonPushed, true);
            app.MarkerEdgeButton.Text = 'Marker Edge';
            app.AxisLimitsSliderLabel = uilabel(app.MarkerOptionsPanel);
            app.AxisLimitsSliderLabel.Text = 'Axis Limits';
            app.AxisLimitsSlider = uislider(app.MarkerOptionsPanel);
            app.AxisLimitsSlider.Limits = [1 10];
            app.AxisLimitsSlider.ValueChangedFcn = createCallbackFcn(app, @AxisLimitsSliderValueChanged, true);
            app.AxisLimitsSlider.Value = 1;
            app.AxisLimitOffsetSliderLabel = uilabel(app.MarkerOptionsPanel);
            app.AxisLimitOffsetSliderLabel.Text = 'Axis Limit Offset';
            app.AxisLimitOffsetSlider = uislider(app.MarkerOptionsPanel);
            app.AxisLimitOffsetSlider.Limits = [1 10];
            app.AxisLimitOffsetSlider.ValueChangedFcn = createCallbackFcn(app, @AxisLimitOffsetSliderValueChanged, true);
            app.AxisLimitOffsetSlider.Value = 1;
            app.StepSizeEditFieldLabel = uilabel(app.MarkerOptionsPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.MarkerOptionsPanel, 'numeric');
            app.StepSizeEditField.Limits = [-10 10];
            app.StepSizeEditField.ValueDisplayFormat = '%.1f';
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Value = 0.5;
            app.TraillineStyleDropDownLabel = uilabel(app.MarkerOptionsPanel);
            app.TraillineStyleDropDownLabel.Text = 'Trail line Style';
            app.TraillineStyleDropDown = uidropdown(app.MarkerOptionsPanel);
            app.TraillineStyleDropDown.Items = {'Solid line', 'Dashed line', 'Dotted line', 'Dash dotted line', 'No line', '', ''};
            app.TraillineStyleDropDown.ItemsData = {'-', '--', ':', '-.', 'none'};
            app.TraillineStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @TraillineStyleDropDownValueChanged, true);
            app.TraillineStyleDropDown.Value = '-';
            app.TraillineColorDropDownLabel = uilabel(app.MarkerOptionsPanel);
            app.TraillineColorDropDownLabel.Text = 'Trail line Color';
            app.TraillineColorDropDown = uidropdown(app.MarkerOptionsPanel);
            app.TraillineColorDropDown.Items = {'Red', 'Green', 'Blue ', 'Cyan', 'Magnenta', 'Yellow', 'Black', 'While'};
            app.TraillineColorDropDown.ItemsData = {'r', 'g', 'b', 'c', 'm', 'y', 'k', 'w'};
            app.TraillineColorDropDown.ValueChangedFcn = createCallbackFcn(app, @TraillineColorDropDownValueChanged, true);
            app.TraillineColorDropDown.Value = 'r';
            app.TraillineWidthEditFieldLabel = uilabel(app.MarkerOptionsPanel);
            app.TraillineWidthEditFieldLabel.Text = 'Trail line Width';
            app.TraillineWidthEditField = uieditfield(app.MarkerOptionsPanel, 'numeric');
            app.TraillineWidthEditField.ValueChangedFcn = createCallbackFcn(app, @TraillineWidthEditFieldValueChanged, true);
            app.TraillineWidthEditField.Value = 2;
            app.NumberofdirectionsSpinnerLabel = uilabel(app.MarkerOptionsPanel);
            app.NumberofdirectionsSpinnerLabel.Text = 'Number of directions';
            app.NumberofdirectionsSpinner = uispinner(app.MarkerOptionsPanel);
            app.NumberofdirectionsSpinner.Limits = [3 Inf];
            app.NumberofdirectionsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofdirectionsSpinnerValueChanged, true);
            app.NumberofdirectionsSpinner.Value = 3;
            app.DISCRETEMODEButton = uibutton(app.MarkerOptionsPanel, 'state');
            app.DISCRETEMODEButton.ValueChangedFcn = createCallbackFcn(app, @DISCRETEMODEButtonValueChanged, true);
            app.DISCRETEMODEButton.Text = 'DISCRETE MODE';
            app.TORUSMODEButton = uibutton(app.MarkerOptionsPanel, 'push');
            app.TORUSMODEButton.ButtonPushedFcn = createCallbackFcn(app, @TORUSMODEButtonPushed, true);
            app.TORUSMODEButton.Text = 'TORUS MODE';
            app.DMODEButton = uibutton(app.MarkerOptionsPanel, 'push');
            app.DMODEButton.ButtonPushedFcn = createCallbackFcn(app, @DMODEButtonPushed, true);
            app.DMODEButton.Text = '3D MODE';
            app.RESETButton = uibutton(app.MarkerOptionsPanel, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.studentNumbersLabel = uilabel(app.UIFigure);
            app.studentNumbersLabel.Text = '281273-272580-282453';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project03_randomWalk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end